classdef ChataSelect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        DrawMidlineButton          matlab.ui.control.Button
        savedirEditField           matlab.ui.control.EditField
        savedirEditFieldLabel      matlab.ui.control.Label
        saveButton                 matlab.ui.control.Button
        Button_4                   matlab.ui.control.Button
        Button_3                   matlab.ui.control.Button
        SelectedcellsListBox       matlab.ui.control.ListBox
        SelectedcellsListBoxLabel  matlab.ui.control.Label
        RefreshButton              matlab.ui.control.Button
        SelectButton               matlab.ui.control.Button
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Data % Data for the app
        z % z plane of choice
    end
    
    methods (Access = private)
        
        function results = plotplanes(app)
            gmranat = app.Data.gmranat;
            gm = app.Data.gm;
            
            z =app.z; 
            ax =app.UIAxes;
            imagesc(ax,gmranat.z(z).L)
            ax.XTick = [];
            ax.YTick = [];
            ax =app.UIAxes_2;
            imagesc(ax,gm.zimg(z).aligngood2)
            ax.XTick = [];
            ax.YTick = [];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, datadir)
            % Load data
            load(fullfile(datadir,'gmranat.mat'));
            load(fullfile(datadir,'gm.mat'))
            app.Data.gmranat = gmranat;
            app.Data.gm = gm;
            app.Data.datadir = datadir;
            app.z = 1; 
            z =1; 
            plotplanes(app)
            %app.savedirEditFieldLabel.Tag = datadir;
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            X = [];
            Y = [];
            gm = app.Data.gm;
            figure
            imagesc(gm.zimg(app.z).aligngood2)
            hold on
            for i = 1:1000
                [x,y,] = ginput(1);
                if isempty(x)
                    break
                end
                X= [X;x];
                Y = [Y;y];
                plot(X,Y,'Color',[0 1 0])
            end
            hold off
            X = [X;X(1)];
            Y = [Y;Y(1)];
            imdim = size(gm.zimg(app.z).aligngood2);
            thismask = poly2mask(X,Y,imdim(1),imdim(2));
            z = app.z;
            gmranat = app.Data.gmranat;
            tim = gmranat.z(z).L;
            
            tcells = tim(thismask>0);
            tcells = tcells(:);
            tcells = unique(tcells(tcells>0));
            cellist = gmranat.z(z).allIdx;
            cellist(gmranat.z(z).excludeIdx) = [];
            tcells = find(ismember(cellist,tcells));
            
            if isempty(app.SelectedcellsListBox.Items{1})
                for i = 1:length(tcells)
                    app.SelectedcellsListBox.Items{i}= char([num2str(app.z) '/' num2str(tcells(i))]);
                end
            else
                for i = 1:length(tcells)
                    app.SelectedcellsListBox.Items{end+1}= char([num2str(app.z) '/' num2str(tcells(i))]);
                end
            end
            
            close
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            z = app.z;
            z = min(z+1,length(app.Data.gmranat.z));
            app.z = z;
            plotplanes(app)
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            z = app.z;
            z = max(z-1,1);
            app.z = z;
            plotplanes(app)
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            disp('saving')
            gmranat = app.Data.gmranat;
            if ~isempty(app.SelectedcellsListBox.Items{1})
                numcells = length(app.SelectedcellsListBox.Items);
                chata_cells = nan(numcells,2);
                for i = 1:numcells
                    tcell = app.SelectedcellsListBox.Items{i};
                    tcell = strsplit(tcell,'/');
                    chata_cells(i,:) = [str2num(tcell{1}) str2num(tcell{2})];
                end
                gmranat.chata_cells = chata_cells;
            end
            save(fullfile(app.Data.datadir,'gmranat.mat'),'gmranat')
            disp('saved')
        end

        % Button pushed function: DrawMidlineButton
        function DrawMidlineButtonPushed(app, event)
            close(figure(101))
            gm = app.Data.gm;
            gmranat = app.Data.gmranat;
            figure(101)
            imagesc(gm.zimg(app.z).aligngood2)
            imsize = size(gm.zimg(app.z).aligngood2);
            hold on
            
            [X,Y] = ginput(2);
            grad = (Y(2)-Y(1))./(X(2)-X(1));
            c = Y(1) - X(1).*grad;
            ys = (1:imsize(2)).*grad +c;
            centroids = cat(1,gmranat.z(app.z).STATScrop.Centroid);
            ycent = ys(round(centroids(:,1))');
            cendiff = centroids(:,2)-ycent';
            lcells = cendiff<0;
            plot(1:imsize(2),ys,'Color',[0 1 0])
            gmranat.z(app.z).lcells = lcells;
            app.Data.gmranat = gmranat;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_2, 'Z')
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.Text = 'Refresh';
            app.SelectedcellsListBoxLabel = uilabel(app.UIFigure);
            app.SelectedcellsListBoxLabel.Text = 'Selected cells';
            app.SelectedcellsListBox = uilistbox(app.UIFigure);
            app.SelectedcellsListBox.Items = {''};
            app.SelectedcellsListBox.Value = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '<<';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '>>';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.savedirEditFieldLabel = uilabel(app.UIFigure);
            app.savedirEditFieldLabel.Text = 'savedir';
            app.savedirEditField = uieditfield(app.UIFigure, 'text');
            app.savedirEditField.Value = 'put directory here';
            app.DrawMidlineButton = uibutton(app.UIFigure, 'push');
            app.DrawMidlineButton.ButtonPushedFcn = createCallbackFcn(app, @DrawMidlineButtonPushed, true);
            app.DrawMidlineButton.Text = 'Draw Midline';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ChataSelect(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end